classdef buffonsNeedle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AnimationresultsPanel           matlab.ui.container.Panel
        TotalneedlesEditField_2         matlab.ui.control.NumericEditField
        TotalneedlesEditField_2Label    matlab.ui.control.Label
        PiestimateEditField_2           matlab.ui.control.NumericEditField
        PiestimateEditField_2Label      matlab.ui.control.Label
        SquarecrossingsEditField_2      matlab.ui.control.NumericEditField
        SquarecrossingsEditField_2Label  matlab.ui.control.Label
        CrossingsEditField_2            matlab.ui.control.NumericEditField
        TotalcrossingsEditField_2Label_2  matlab.ui.control.Label
        ConsecutivecrossingsEditField_2  matlab.ui.control.NumericEditField
        ConsecutivecrossingsEditField_2Label  matlab.ui.control.Label
        AnimationcontrolPanel           matlab.ui.container.Panel
        BackwardsButton                 matlab.ui.control.Button
        ForwardsButton                  matlab.ui.control.Button
        PauseButton                     matlab.ui.control.StateButton
        AnimationspeedSlider            matlab.ui.control.Slider
        AnimationspeedSliderLabel       matlab.ui.control.Label
        ResultssettingsPanel            matlab.ui.container.Panel
        FontsizeSlider                  matlab.ui.control.Slider
        FontsizeLabel                   matlab.ui.control.Label
        DecimalplacesSlider             matlab.ui.control.Slider
        DecimalplacesSliderLabel        matlab.ui.control.Label
        BackgroundcolourColorPicker     matlab.ui.control.ColorPicker
        BackgroundcolourColorPickerLabel  matlab.ui.control.Label
        FontcolourColorPicker           matlab.ui.control.ColorPicker
        FontcolourColorPickerLabel      matlab.ui.control.Label
        GraphsettingsPanel              matlab.ui.container.Panel
        BackgroundColorPicker           matlab.ui.control.ColorPicker
        BackgroundColorPickerLabel      matlab.ui.control.Label
        LinesColorPicker                matlab.ui.control.ColorPicker
        LinesColorPickerLabel           matlab.ui.control.Label
        SimilarneedlesColorPicker       matlab.ui.control.ColorPicker
        SimilarneedlesColorPickerLabel  matlab.ui.control.Label
        SelectedneedleColorPicker       matlab.ui.control.ColorPicker
        SelectedneedleColorPickerLabel  matlab.ui.control.Label
        NeedlesontopoflinesColorPicker  matlab.ui.control.ColorPicker
        NeedlesontopoflinesColorPickerLabel  matlab.ui.control.Label
        NeedlesbetweenlinesColorPicker  matlab.ui.control.ColorPicker
        NeedlesbetweenlinesColorPickerLabel  matlab.ui.control.Label
        ParametersPanel                 matlab.ui.container.Panel
        AnimatesimulationCheckBox       matlab.ui.control.CheckBox
        RunSimulationButton             matlab.ui.control.Button
        SimulationmodeButtonGroup       matlab.ui.container.ButtonGroup
        SquaresRootTwoButton            matlab.ui.control.RadioButton
        SquaresPiButton                 matlab.ui.control.RadioButton
        StandardPiButton                matlab.ui.control.RadioButton
        LinesEditField                  matlab.ui.control.NumericEditField
        LinesEditFieldLabel             matlab.ui.control.Label
        LinewidthEditField              matlab.ui.control.NumericEditField
        LinewidthEditFieldLabel         matlab.ui.control.Label
        NeedlelengthEditField           matlab.ui.control.NumericEditField
        NeedlelengthEditFieldLabel      matlab.ui.control.Label
        ShowsimilarneedlesEditField     matlab.ui.control.NumericEditField
        ShowsimilarneedlesEditFieldLabel  matlab.ui.control.Label
        ThrowsEditField                 matlab.ui.control.NumericEditField
        ThrowsEditFieldLabel            matlab.ui.control.Label
        ThrowsvisibleEditField          matlab.ui.control.NumericEditField
        ThrowsvisibleEditFieldLabel     matlab.ui.control.Label
        SimulationresultsPanel          matlab.ui.container.Panel
        TotalneedlesEditField           matlab.ui.control.NumericEditField
        TotalneedlesEditFieldLabel      matlab.ui.control.Label
        SquarecrossingsEditField        matlab.ui.control.NumericEditField
        SquarecrossingsEditFieldLabel   matlab.ui.control.Label
        CrossingsEditField              matlab.ui.control.NumericEditField
        CrossingsEditFieldLabel         matlab.ui.control.Label
        ConsecutivecrossingsEditField   matlab.ui.control.NumericEditField
        ConsecutivecrossingsEditFieldLabel  matlab.ui.control.Label
        PiestimateEditField             matlab.ui.control.NumericEditField
        PiestimateEditFieldLabel        matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        sides;
        width;
        length;
        linesX;
        linesY;
        endPointsX;
        endPointsY;
        crossesLine;
        maximumX;
        angles;
        target;
        similar;
        frameNumber;
        needlesVisible;
        rootTwo;
        animating
    end
    
    methods (Access = private)
        
        function plotNeedles(app)

            % Clear axes
            cla(app.UIAxes)

            % Set axes
            app.UIAxes.HitTest="on";
            app.UIAxes.XLim = [0, app.maximumX];
            app.UIAxes.YLim = [0, app.maximumX];

            % Get selected colours
            app.UIAxes.Color = app.BackgroundColorPicker.Value;
            linesColour = app.LinesColorPicker.Value;
            betweenColour = app.NeedlesbetweenlinesColorPicker.Value;
            onTopColour = app.NeedlesontopoflinesColorPicker.Value;
            selectedColour = app.SelectedneedleColorPicker.Value;
            similarColour = app.SimilarneedlesColorPicker.Value;

            % Plot parallel lines
            hold(app.UIAxes, "on");
            plot(app.UIAxes, app.linesX, app.linesY, ...
                color = linesColour, HitTest="off");

            % If animating plot one by one
            if app.AnimatesimulationCheckBox.Value
                app.animating = true;

                % Enable animation results and controls
                app.AnimationresultsPanel.Enable = "on";
                app.AnimationcontrolPanel.Enable = "on";

                % Disable axes clicking
                app.UIAxes.PickableParts = "none"
                
                % If starting from partway through, plot previous frames
                if app.frameNumber ~= 0

                    % Select needles from previous frames
                    endPointsX = app.endPointsX(1:app.frameNumber,:);
                    endPointsY = app.endPointsY(1:app.frameNumber,:);
                    crossesLine = app.crossesLine(1:app.frameNumber);
                    
                    % Plot needles between lines from previous frames
                    plot(app.UIAxes, endPointsX(~crossesLine,:).', ...
                        endPointsY(~crossesLine,:).', ...
                        color=betweenColour, HitTest="off");
                
                    % Plot needles on top of lines from previous frames
                    plot(app.UIAxes, endPointsX(crossesLine,:).', ...
                        endPointsY(crossesLine,:).', ...
                        color=onTopColour, HitTest="off");

                    % Update animation  results
                    crossings = sum(crossesLine);
                    app.CrossingsEditField_2.Value = crossings;
                    app.TotalneedlesEditField_2.Value = app.frameNumber;

                    if app.rootTwo

                        % Estimate root two and count square and consecutive 
                        % crossings for values up to current frame
                        [r, ~, squareCrossings, consecutiveCrossings] = ...
                            estimateRootTwo(crossesLine);
                        app.PiestimateEditField_2.Value = r;
                        app.SquarecrossingsEditField_2.Value = squareCrossings;
                        app.ConsecutivecrossingsEditField_2.Value = consecutiveCrossings;
                    else
                        % Estimate pi for values up to current frame
                        app.PiestimateEditField_2.Value = ... 
                            estimatePi(app.width,app.length,app.frameNumber,crossings);
                    end
                end

                % Get animation speed from slider
                needlesPerSecond = round(1/app.AnimationspeedSlider.Value,1);
                
                % Repeat until paused, new simulation or all needles plotted
                while app.PauseButton.Value == false ...
                    && app.animating == true ...
                    && app.frameNumber < app.needlesVisible 
                   
                    % Add one needle, or four needles for squares
                    firstFrame = app.frameNumber + 1;
                    app.frameNumber = app.frameNumber + app.sides;
 
                    % Select next needles to be shown
                    endPointsX = app.endPointsX( ...
                        firstFrame : app.frameNumber,:);
                    endPointsY = app.endPointsY( ...
                        firstFrame : app.frameNumber,:);
                    crossesLine = app.crossesLine( ...
                        firstFrame : app.frameNumber);

                    % Plot needles between lines
                    plot(app.UIAxes, endPointsX(~crossesLine,:).', ...
                        endPointsY(~crossesLine,:).', ...
                        color=betweenColour, HitTest="off");
            
                    % Plot needles on top of lines
                    plot(app.UIAxes, endPointsX(crossesLine,:).', ...
                        endPointsY(crossesLine,:).', ...
                        color=onTopColour, HitTest="off");

                    % Update animation  results
                    crossings = sum(app.crossesLine(1:app.frameNumber));
                    app.CrossingsEditField_2.Value = crossings;
                    app.TotalneedlesEditField_2.Value = app.frameNumber;
                    if app.rootTwo
                        % Estimate root two and count square and consecutive 
                        % crossings for values up to current frame
                        [r, ~, squareCrossings, consecutiveCrossings] = ...
                            estimateRootTwo(app.crossesLine(1:app.frameNumber));

                        app.PiestimateEditField_2.Value = r;
                        app.SquarecrossingsEditField_2.Value = squareCrossings;
                        app.ConsecutivecrossingsEditField_2.Value = consecutiveCrossings;
                    else
                        % Estimate pi for values up to current frame
                        app.PiestimateEditField_2.Value = ... 
                            estimatePi(app.width,app.length,app.frameNumber,crossings);
                    end
                    pause(needlesPerSecond);
                end
                % Reenable axes clicking if animation is finished
                if app.sides == 1 && app.frameNumber == app.needlesVisible 
                    app.UIAxes.PickableParts = "all";
                end
            % If not animating plot all needles
            else
                app.animating = false;

                % Disable animation results and controls
                app.AnimationresultsPanel.Enable = "off";
                app.AnimationcontrolPanel.Enable = "off";

                % Plot needles between lines
                plot(app.UIAxes, app.endPointsX(~app.crossesLine,:).', ...
                    app.endPointsY(~app.crossesLine,:).', ...
                    color=betweenColour, HitTest="off");
            
                % Plot needles on top of lines
                plot(app.UIAxes, app.endPointsX(app.crossesLine,:).', ...
                    app.endPointsY(app.crossesLine,:).', ...
                    color=onTopColour, HitTest="off");
            end
            % Plot selected needle
            plot(app.UIAxes, app.endPointsX(app.target,:).', ...
                app.endPointsY(app.target,:).', Color=selectedColour);

            % Plot similar needles
            plot(app.UIAxes, app.endPointsX(app.similar,:).', ...
                app.endPointsY(app.similar,:).', Color=similarColour);
        end
        
        function simulateNeedles(app, centres, angles)
            
            lines = app.LinesEditField.Value;
            width = app.width;
            length = app.length;

            % Calculate position of endpoints of each needle
            horizontalProjection = (length * cos(angles)) / 2;
            verticalProjection = (length * sin(angles)) / 2;
            endPointsX = [centres(:,1) - horizontalProjection,...
                centres(:,1) + horizontalProjection];
            endPointsY = [centres(:,2) - verticalProjection,...
                centres(:,2) + verticalProjection];
            
            [needlesVisible,~] = size(angles);
            throwsVisible = needlesVisible / app.sides;

            % Swap every other point's Y values for squares
            if app.sides == 4
                endPointsY(1:2:needlesVisible, :) = ...
                    endPointsY(1:2:needlesVisible, [2 1]);
            end

            maximumX = width * (lines -1);
           
            % Generate parallel lines
            lineStep = 0:width:maximumX;
            linesX = [lineStep; lineStep];
            linesY = [zeros(1, lines) - length/2; 
                maximumX * ones(1, lines) + length/2];

            % Detect needles that cross a line
            crossesLine = sign(endPointsX(:,1)) ~= sign(endPointsX(:,2));

            % Generate random vectors to distribute needles evenly
            flipX = randi(2, throwsVisible, 1) - 1;
            flipY = sign(randn(throwsVisible,1));
            spreadX = lineStep(randi(numel(lineStep)-1,throwsVisible, 1)).';
            spreadY = rand(throwsVisible,1) * maximumX;

            % Expand random vectors for squares
            flipX = repelem(flipX, app.sides, 1);
            flipY = repelem(flipY, app.sides, 1);
            spreadX = repelem(spreadX, app.sides, 1);
            spreadY = repelem(spreadY, app.sides, 1);

            % Spread points randomly
            endPointsX = (width .* flipX) + (endPointsX .* (1 + -2 * (flipX )));
            endPointsX = endPointsX + spreadX;
            endPointsY = endPointsY .* flipY;
            endPointsY = endPointsY + spreadY;

            app.linesX = linesX;
            app.linesY = linesY;
            app.maximumX = maximumX;
            app.endPointsX = endPointsX;
            app.endPointsY = endPointsY;
            app.crossesLine = crossesLine;
            app.angles = angles;
            app.needlesVisible = needlesVisible;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function runSimulation(app, event)

            % Get variables from menu
            width = app.LinewidthEditField.Value;
            length = app.NeedlelengthEditField.Value;
            throws = app.ThrowsEditField.Value;
            throwsVisible = app.ThrowsvisibleEditField.Value;
            if throwsVisible > throws
                throwsVisible = throws;
            end

            % Reset needle clicked and similar needles
            app.target = [];
            app.similar = [];

            % Reset animation outputs
            app.PiestimateEditField_2.Value = [];
            app.TotalneedlesEditField_2.Value = [];
            app.CrossingsEditField_2.Value = [];
            app.SquarecrossingsEditField_2.Value = [];
            app.ConsecutivecrossingsEditField_2.Value = [];

            % Hide square crossings and consecutive crossings by default
            app.SquarecrossingsEditField.Enable = "off";
            app.ConsecutivecrossingsEditField.Enable = "off";
            app.SquarecrossingsEditField_2.Enable = "off";
            app.ConsecutivecrossingsEditField_2.Enable = "off";
            app.SquarecrossingsEditField.Value = [];
            app.ConsecutivecrossingsEditField.Value = [];

            % Select which simulation to run
            % Standard pi
            app.rootTwo = false;
            if app.StandardPiButton.Value == true
                
                % One needle per throw
                sides = 1;

                % Enable needle clicking
                app.ShowsimilarneedlesEditField.Enable = "on";
                
                % Run standard simulation
                app.PiestimateEditFieldLabel.Text = "Pi estimate";
                app.PiestimateEditField_2Label.Text = "Pi estimate";
                [result,totalCrossings,centreX,angles] = BuffonStandardPi(width,length,throws);
                centres = [centreX, zeros(throws,1)];

                % Set the show similar needles limit
                app.ShowsimilarneedlesEditField.Limits = [0,throwsVisible-1];

            % Squares
            else
                % One square needs four needles
                sides = 4;

                % Disable needle clicking
                app.ShowsimilarneedlesEditField.Enable = "off";

                % Squares pi
                if app.SquaresPiButton.Value == true
                    app.PiestimateEditFieldLabel.Text = "Pi estimate";
                    app.PiestimateEditField_2Label.Text = "Pi estimate";
                    [result,totalCrossings,centres,angles] ...
                        = BuffonSquaresPi(width,length,throws);

                % Squares root two
                else
                    app.rootTwo = true;

                    % Makes sure square can only intersect 1 line
                    if sqrt(2) * length >= width
                        length = round(width / sqrt(2) - 0.5);
                        app.NeedlelengthEditField.Value = length;
                    end

                    % Run squares root two simulation
                    app.PiestimateEditFieldLabel.Text = "Root two estimate";
                    app.PiestimateEditField_2Label.Text = "Root two estimate";
                    [result,totalCrossings,squareCrossings, ...
                        consecutiveCrossings,centres,angles] ...
                        = BuffonSquaresRootTwo(width,length,throws);

                    % Display square crossings and consecutive crossings
                    app.SquarecrossingsEditField.Enable = "on";
                    app.ConsecutivecrossingsEditField.Enable = "on";
                    app.SquarecrossingsEditField_2.Enable = "on";
                    app.ConsecutivecrossingsEditField_2.Enable = "on";
                    app.SquarecrossingsEditField.Value = squareCrossings;
                    app.ConsecutivecrossingsEditField.Value = consecutiveCrossings;
                end
            end

            % Output result
            app.CrossingsEditField.Value = totalCrossings;
            app.TotalneedlesEditField.Value = throws * sides;
            app.PiestimateEditField.Value = result;

            % Limit needles shown
            centres = centres(1:throwsVisible * sides,:);
            angles = angles(1:throwsVisible * sides);
            
            app.width = width;
            app.length = length;
            app.sides = sides;
            app.frameNumber = 0;
            simulateNeedles(app, centres, angles);
            plotNeedles(app);
            
        end

        % Button down function: UIAxes
        function axesClicked(app, event)
            if app.sides == 1
                % Set mouse and needle endpoint positions
                mouseX = app.UIAxes.CurrentPoint(1,1);
                mouseY = app.UIAxes.CurrentPoint(1,2);
                endPointsX = app.endPointsX;
                endPointsY = app.endPointsY;
                x1 = endPointsX(:,1);
                x2 = endPointsX(:,2);
                y1 = endPointsY(:,1);
                y2 = endPointsY(:,2);
                xDif = x2-x1;
                yDif = y2-y1;
    
                % Find distances from mouse to all infinite lines defined by 
                % needle endPoints
                lineDistance = abs(yDif.*mouseX - xDif.*mouseY + x2.*y1 - y2.*x1)...
                    ./ sqrt(yDif.^2 + xDif.^2);
                
                % Find X value of closest point to the mouse on infinite line
                needleGradient = yDif ./ xDif;
                mouseGradient = -1 ./ needleGradient;
                needleIntercept = y1 - needleGradient .* x1;
                mouseIntercept = mouseY - mouseGradient .* mouseX;
                closestX = (needleGradient .* x1 + needleIntercept - mouseIntercept)...
                    ./ mouseGradient;
    
                % Calculate whether the closest point on the infinite line is 
                % a point on the needle
                mouseOutsideNeedle = (closestX < x1) & (closestX < x2)...
                    | (closestX > x1) & (closestX > x2);
                
                % Find distances from mouse to needle endpoints
                endPointDistance1 = hypot(mouseX - x1, mouseY - y1);
                endPointDistance2 = hypot(mouseX - x2, mouseY - y2);
    
                % Select only the appropriate distances
                endPointDistance1 = endPointDistance1 .* mouseOutsideNeedle;
                endPointDistance2 = endPointDistance2 .* mouseOutsideNeedle;
                lineDistance = lineDistance .* ~mouseOutsideNeedle;
    
                % Combine distances into one matrix
                distance = [endPointDistance1 + lineDistance,...
                    endPointDistance2 + lineDistance];
                
                % Find row number of the needle with the smallest distance
                [~,index] = min(distance,[],"all");
                [targetRow,~] = ind2sub(size(distance), index);
                app.target = targetRow;
    
                % If needle gradient is negative, angle = pi - angle 
                negativeGradient = sign(needleGradient) == -1;
                angles = app.angles .* ~negativeGradient + ...
                    (pi - app.angles) .* negativeGradient;
    
                % rank angle similarity
                targetAngle = angles(targetRow);
                angleDifference = ones(size(angles)) .* targetAngle - angles;
                [~,similarityRank] = sort(abs(angleDifference));
                
                % Find the n most similar needles (excluding itself)
                n = app.ShowsimilarneedlesEditField.Value;
                app.similar = similarityRank(2:n+1);
    
                % Plot needles
                app.plotNeedles();
            end
        end

        % Callback function
        function simulationModeChanged(app, event)

        end

        % Value changing function: FontsizeSlider
        function fontSizeChanging(app, event)
            fontSize = event.Value;
            app.PiestimateEditField.FontSize = fontSize;
            app.TotalneedlesEditField.FontSize = fontSize;
            app.CrossingsEditField.FontSize = fontSize;
            app.SquarecrossingsEditField.FontSize = fontSize;
            app.ConsecutivecrossingsEditField.FontSize = fontSize;
            app.PiestimateEditField_2.FontSize = fontSize;
            app.TotalneedlesEditField_2.FontSize = fontSize;
            app.CrossingsEditField_2.FontSize = fontSize;
            app.SquarecrossingsEditField_2.FontSize = fontSize;
            app.ConsecutivecrossingsEditField_2.FontSize = fontSize;
        end

        % Callback function: NeedlesbetweenlinesColorPicker
        function betweenLinesColour(app, event)
            app.plotNeedles();
        end

        % Callback function: LinesColorPicker
        function linesColour(app, event)
            app.plotNeedles();
        end

        % Callback function: NeedlesontopoflinesColorPicker
        function onTopColour(app, event)
            app.plotNeedles();
        end

        % Callback function: BackgroundColorPicker
        function backgroundColour(app, event)
            app.plotNeedles();
        end

        % Callback function: SelectedneedleColorPicker
        function selectedColour(app, event)
            app.plotNeedles();
        end

        % Callback function: SimilarneedlesColorPicker
        function similarColour(app, event)
            app.plotNeedles();
        end

        % Callback function
        function decimalPlaces(app, event)

        end

        % Callback function: FontcolourColorPicker
        function resultsFontColour(app, event)
            colour = app.FontcolourColorPicker.Value;
            app.PiestimateEditField.FontColor = colour;
            app.TotalneedlesEditField.FontColor = colour;
            app.CrossingsEditField.FontColor = colour;
            app.SquarecrossingsEditField.FontColor = colour;
            app.ConsecutivecrossingsEditField.FontColor = colour;
            app.PiestimateEditField_2.FontColor = colour;
            app.TotalneedlesEditField_2.FontColor = colour;
            app.CrossingsEditField_2.FontColor = colour;
            app.SquarecrossingsEditField_2.FontColor = colour;
            app.ConsecutivecrossingsEditField_2.FontColor = colour;
        end

        % Callback function
        function paused(app, event)
            value = app.PauseButton.Value;
            
        end

        % Value changed function: PauseButton
        function pauseButtonPressed(app, event)
            paused = app.PauseButton.Value;
            if paused
                app.PauseButton.Text = "Play ▶";
                app.animating = false;
            else
                app.PauseButton.Text = "Pause ||";
                app.animating = true;
                app.plotNeedles();
            end
        end

        % Button pushed function: BackwardsButton
        function backwardsButtonPressed(app, event)
            if app.frameNumber ~= 0
                app.frameNumber = app.frameNumber - app.sides;

                app.plotNeedles();
            end
        end

        % Button pushed function: ForwardsButton
        function forwardsButtonPressed(app, event)
            if app.frameNumber ~= app.needlesVisible
                app.frameNumber = app.frameNumber + app.sides;
                app.plotNeedles;
            end
        end

        % Value changing function: AnimationspeedSlider
        function animationSpeedChanging(app, event)
            
        end

        % Value changed function: AnimationspeedSlider
        function animationSpeedChanged(app, event)
            app.plotNeedles(); 
        end

        % Value changed function: DecimalplacesSlider
        function decimalPlacesChanged(app, event)

        end

        % Value changing function: DecimalplacesSlider
        function decimalPlacesChanging(app, event)
            changingValue = event.Value;
            app.PiestimateEditField.ValueDisplayFormat = "%."+changingValue+"f";
            app.PiestimateEditField_2.ValueDisplayFormat = "%."+changingValue+"f";
        end

        % Callback function: BackgroundcolourColorPicker
        function backGroundColourChanged(app, event)
            colour = app.BackgroundcolourColorPicker.Value;
            app.PiestimateEditField.BackgroundColor = colour;
            app.TotalneedlesEditField.BackgroundColor = colour;
            app.CrossingsEditField.BackgroundColor = colour;
            app.SquarecrossingsEditField.BackgroundColor = colour;
            app.ConsecutivecrossingsEditField.BackgroundColor = colour;
            app.PiestimateEditField_2.BackgroundColor = colour;
            app.TotalneedlesEditField_2.BackgroundColor = colour;
            app.CrossingsEditField_2.BackgroundColor = colour;
            app.SquarecrossingsEditField_2.BackgroundColor = colour;
            app.ConsecutivecrossingsEditField_2.BackgroundColor = colour;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Buffon''s needle')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [0 10];
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @axesClicked, true);
            app.SimulationresultsPanel = uipanel(app.UIFigure);
            app.SimulationresultsPanel.Title = 'Simulation results';
            app.PiestimateEditFieldLabel = uilabel(app.SimulationresultsPanel);
            app.PiestimateEditFieldLabel.Text = 'Pi estimate';
            app.PiestimateEditField = uieditfield(app.SimulationresultsPanel, 'numeric');
            app.PiestimateEditField.ValueDisplayFormat = '%.5f';
            app.PiestimateEditField.AllowEmpty = 'on';
            app.PiestimateEditField.Editable = 'off';
            app.PiestimateEditField.Value = [];
            app.ConsecutivecrossingsEditFieldLabel = uilabel(app.SimulationresultsPanel);
            app.ConsecutivecrossingsEditFieldLabel.Text = 'Consecutive crossings';
            app.ConsecutivecrossingsEditField = uieditfield(app.SimulationresultsPanel, 'numeric');
            app.ConsecutivecrossingsEditField.ValueDisplayFormat = '%.0f';
            app.ConsecutivecrossingsEditField.AllowEmpty = 'on';
            app.ConsecutivecrossingsEditField.Editable = 'off';
            app.ConsecutivecrossingsEditField.Value = [];
            app.CrossingsEditFieldLabel = uilabel(app.SimulationresultsPanel);
            app.CrossingsEditFieldLabel.Text = 'Crossings';
            app.CrossingsEditField = uieditfield(app.SimulationresultsPanel, 'numeric');
            app.CrossingsEditField.ValueDisplayFormat = '%.0f';
            app.CrossingsEditField.AllowEmpty = 'on';
            app.CrossingsEditField.Editable = 'off';
            app.CrossingsEditField.Value = [];
            app.SquarecrossingsEditFieldLabel = uilabel(app.SimulationresultsPanel);
            app.SquarecrossingsEditFieldLabel.Text = 'Square crossings';
            app.SquarecrossingsEditField = uieditfield(app.SimulationresultsPanel, 'numeric');
            app.SquarecrossingsEditField.ValueDisplayFormat = '%.0f';
            app.SquarecrossingsEditField.AllowEmpty = 'on';
            app.SquarecrossingsEditField.Editable = 'off';
            app.SquarecrossingsEditField.Value = [];
            app.TotalneedlesEditFieldLabel = uilabel(app.SimulationresultsPanel);
            app.TotalneedlesEditFieldLabel.Text = 'Total needles';
            app.TotalneedlesEditField = uieditfield(app.SimulationresultsPanel, 'numeric');
            app.TotalneedlesEditField.ValueDisplayFormat = '%.0f';
            app.TotalneedlesEditField.AllowEmpty = 'on';
            app.TotalneedlesEditField.Editable = 'off';
            app.TotalneedlesEditField.Value = [];
            app.ParametersPanel = uipanel(app.UIFigure);
            app.ParametersPanel.Title = 'Parameters';
            app.ThrowsvisibleEditFieldLabel = uilabel(app.ParametersPanel);
            app.ThrowsvisibleEditFieldLabel.Text = 'Throws visible';
            app.ThrowsvisibleEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ThrowsvisibleEditField.Limits = [1 1000];
            app.ThrowsvisibleEditField.RoundFractionalValues = 'on';
            app.ThrowsvisibleEditField.ValueDisplayFormat = '%.0f';
            app.ThrowsvisibleEditField.Value = 100;
            app.ThrowsEditFieldLabel = uilabel(app.ParametersPanel);
            app.ThrowsEditFieldLabel.Text = 'Throws';
            app.ThrowsEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ThrowsEditField.Limits = [1 10000000];
            app.ThrowsEditField.RoundFractionalValues = 'on';
            app.ThrowsEditField.ValueDisplayFormat = '%.0f';
            app.ThrowsEditField.Value = 100000;
            app.ShowsimilarneedlesEditFieldLabel = uilabel(app.ParametersPanel);
            app.ShowsimilarneedlesEditFieldLabel.Text = 'Show similar needles';
            app.ShowsimilarneedlesEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.ShowsimilarneedlesEditField.Limits = [1 Inf];
            app.ShowsimilarneedlesEditField.RoundFractionalValues = 'on';
            app.ShowsimilarneedlesEditField.ValueDisplayFormat = '%.0f';
            app.ShowsimilarneedlesEditField.Value = 3;
            app.NeedlelengthEditFieldLabel = uilabel(app.ParametersPanel);
            app.NeedlelengthEditFieldLabel.Text = 'Needle length';
            app.NeedlelengthEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.NeedlelengthEditField.Limits = [2.22044604925031e-16 Inf];
            app.NeedlelengthEditField.ValueDisplayFormat = '%.1f';
            app.NeedlelengthEditField.Value = 5;
            app.LinewidthEditFieldLabel = uilabel(app.ParametersPanel);
            app.LinewidthEditFieldLabel.Text = 'Line width';
            app.LinewidthEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.LinewidthEditField.Limits = [2.22044604925031e-16 Inf];
            app.LinewidthEditField.ValueDisplayFormat = '%.1f';
            app.LinewidthEditField.Value = 10;
            app.LinesEditFieldLabel = uilabel(app.ParametersPanel);
            app.LinesEditFieldLabel.Text = 'Lines';
            app.LinesEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.LinesEditField.Limits = [2 100];
            app.LinesEditField.RoundFractionalValues = 'on';
            app.LinesEditField.ValueDisplayFormat = '%.0f';
            app.LinesEditField.Value = 10;
            app.SimulationmodeButtonGroup = uibuttongroup(app.ParametersPanel);
            app.SimulationmodeButtonGroup.Title = 'Simulation mode';
            app.StandardPiButton = uiradiobutton(app.SimulationmodeButtonGroup);
            app.StandardPiButton.Text = 'StandardPi';
            app.StandardPiButton.Value = true;
            app.SquaresPiButton = uiradiobutton(app.SimulationmodeButtonGroup);
            app.SquaresPiButton.Text = 'SquaresPi';
            app.SquaresRootTwoButton = uiradiobutton(app.SimulationmodeButtonGroup);
            app.SquaresRootTwoButton.Text = 'SquaresRootTwo';
            app.RunSimulationButton = uibutton(app.ParametersPanel, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @runSimulation, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.AnimatesimulationCheckBox = uicheckbox(app.ParametersPanel);
            app.AnimatesimulationCheckBox.Text = 'Animate simulation';
            app.AnimatesimulationCheckBox.Value = true;
            app.GraphsettingsPanel = uipanel(app.UIFigure);
            app.GraphsettingsPanel.Title = 'Graph settings';
            app.NeedlesbetweenlinesColorPickerLabel = uilabel(app.GraphsettingsPanel);
            app.NeedlesbetweenlinesColorPickerLabel.Text = 'Needles between lines';
            app.NeedlesbetweenlinesColorPicker = uicolorpicker(app.GraphsettingsPanel);
            app.NeedlesbetweenlinesColorPicker.Value = [0 0 1];
            app.NeedlesbetweenlinesColorPicker.ValueChangedFcn = createCallbackFcn(app, @betweenLinesColour, true);
            app.NeedlesontopoflinesColorPickerLabel = uilabel(app.GraphsettingsPanel);
            app.NeedlesontopoflinesColorPickerLabel.Text = 'Needles on top of lines';
            app.NeedlesontopoflinesColorPicker = uicolorpicker(app.GraphsettingsPanel);
            app.NeedlesontopoflinesColorPicker.Value = [0 1 1];
            app.NeedlesontopoflinesColorPicker.ValueChangedFcn = createCallbackFcn(app, @onTopColour, true);
            app.SelectedneedleColorPickerLabel = uilabel(app.GraphsettingsPanel);
            app.SelectedneedleColorPickerLabel.Text = 'Selected needle';
            app.SelectedneedleColorPicker = uicolorpicker(app.GraphsettingsPanel);
            app.SelectedneedleColorPicker.Value = [0 1 0];
            app.SelectedneedleColorPicker.ValueChangedFcn = createCallbackFcn(app, @selectedColour, true);
            app.SimilarneedlesColorPickerLabel = uilabel(app.GraphsettingsPanel);
            app.SimilarneedlesColorPickerLabel.Text = 'Similar needles';
            app.SimilarneedlesColorPicker = uicolorpicker(app.GraphsettingsPanel);
            app.SimilarneedlesColorPicker.Value = [1 0 1];
            app.SimilarneedlesColorPicker.ValueChangedFcn = createCallbackFcn(app, @similarColour, true);
            app.LinesColorPickerLabel = uilabel(app.GraphsettingsPanel);
            app.LinesColorPickerLabel.Text = 'Lines';
            app.LinesColorPicker = uicolorpicker(app.GraphsettingsPanel);
            app.LinesColorPicker.ValueChangedFcn = createCallbackFcn(app, @linesColour, true);
            app.BackgroundColorPickerLabel = uilabel(app.GraphsettingsPanel);
            app.BackgroundColorPickerLabel.Text = 'Background';
            app.BackgroundColorPicker = uicolorpicker(app.GraphsettingsPanel);
            app.BackgroundColorPicker.Value = [0 0 0];
            app.BackgroundColorPicker.ValueChangedFcn = createCallbackFcn(app, @backgroundColour, true);
            app.ResultssettingsPanel = uipanel(app.UIFigure);
            app.ResultssettingsPanel.Title = 'Results settings';
            app.FontcolourColorPickerLabel = uilabel(app.ResultssettingsPanel);
            app.FontcolourColorPickerLabel.Text = 'Font colour';
            app.FontcolourColorPicker = uicolorpicker(app.ResultssettingsPanel);
            app.FontcolourColorPicker.Value = [0.9412 0.9412 0.9412];
            app.FontcolourColorPicker.ValueChangedFcn = createCallbackFcn(app, @resultsFontColour, true);
            app.BackgroundcolourColorPickerLabel = uilabel(app.ResultssettingsPanel);
            app.BackgroundcolourColorPickerLabel.Text = 'Background colour';
            app.BackgroundcolourColorPicker = uicolorpicker(app.ResultssettingsPanel);
            app.BackgroundcolourColorPicker.Value = [0.101960784313725 0.101960784313725 0.101960784313725];
            app.BackgroundcolourColorPicker.ValueChangedFcn = createCallbackFcn(app, @backGroundColourChanged, true);
            app.DecimalplacesSliderLabel = uilabel(app.ResultssettingsPanel);
            app.DecimalplacesSliderLabel.Text = 'Decimal places';
            app.DecimalplacesSlider = uislider(app.ResultssettingsPanel);
            app.DecimalplacesSlider.Limits = [0 30];
            app.DecimalplacesSlider.MajorTicks = [0 3 6 9 12 15 18 21 24 27 30];
            app.DecimalplacesSlider.ValueChangedFcn = createCallbackFcn(app, @decimalPlacesChanged, true);
            app.DecimalplacesSlider.ValueChangingFcn = createCallbackFcn(app, @decimalPlacesChanging, true);
            app.DecimalplacesSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30];
            app.DecimalplacesSlider.Step = 1;
            app.DecimalplacesSlider.Value = 8;
            app.FontsizeLabel = uilabel(app.ResultssettingsPanel);
            app.FontsizeLabel.Text = 'Font size';
            app.FontsizeSlider = uislider(app.ResultssettingsPanel);
            app.FontsizeSlider.Limits = [10 100];
            app.FontsizeSlider.ValueChangingFcn = createCallbackFcn(app, @fontSizeChanging, true);
            app.FontsizeSlider.Value = 40;
            app.AnimationcontrolPanel = uipanel(app.UIFigure);
            app.AnimationcontrolPanel.Title = 'Animation control';
            app.AnimationspeedSliderLabel = uilabel(app.AnimationcontrolPanel);
            app.AnimationspeedSliderLabel.Text = 'Animation speed';
            app.AnimationspeedSlider = uislider(app.AnimationcontrolPanel);
            app.AnimationspeedSlider.Limits = [0.2 20];
            app.AnimationspeedSlider.MajorTicks = [0.2 10 20 30 40 50];
            app.AnimationspeedSlider.ValueChangedFcn = createCallbackFcn(app, @animationSpeedChanged, true);
            app.AnimationspeedSlider.ValueChangingFcn = createCallbackFcn(app, @animationSpeedChanging, true);
            app.AnimationspeedSlider.MinorTicks = [0.2 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50];
            app.AnimationspeedSlider.Value = 2;
            app.PauseButton = uibutton(app.AnimationcontrolPanel, 'state');
            app.PauseButton.ValueChangedFcn = createCallbackFcn(app, @pauseButtonPressed, true);
            app.PauseButton.Text = 'Pause | |';
            app.ForwardsButton = uibutton(app.AnimationcontrolPanel, 'push');
            app.ForwardsButton.ButtonPushedFcn = createCallbackFcn(app, @forwardsButtonPressed, true);
            app.ForwardsButton.Text = 'Forwards ▶|';
            app.BackwardsButton = uibutton(app.AnimationcontrolPanel, 'push');
            app.BackwardsButton.ButtonPushedFcn = createCallbackFcn(app, @backwardsButtonPressed, true);
            app.BackwardsButton.Text = 'Backwards |◀';
            app.AnimationresultsPanel = uipanel(app.UIFigure);
            app.AnimationresultsPanel.Title = 'Animation results';
            app.ConsecutivecrossingsEditField_2Label = uilabel(app.AnimationresultsPanel);
            app.ConsecutivecrossingsEditField_2Label.Text = 'Consecutive crossings';
            app.ConsecutivecrossingsEditField_2 = uieditfield(app.AnimationresultsPanel, 'numeric');
            app.ConsecutivecrossingsEditField_2.ValueDisplayFormat = '%.0f';
            app.ConsecutivecrossingsEditField_2.AllowEmpty = 'on';
            app.ConsecutivecrossingsEditField_2.Editable = 'off';
            app.ConsecutivecrossingsEditField_2.Value = [];
            app.TotalcrossingsEditField_2Label_2 = uilabel(app.AnimationresultsPanel);
            app.TotalcrossingsEditField_2Label_2.Text = 'Crossings';
            app.CrossingsEditField_2 = uieditfield(app.AnimationresultsPanel, 'numeric');
            app.CrossingsEditField_2.ValueDisplayFormat = '%.0f';
            app.CrossingsEditField_2.AllowEmpty = 'on';
            app.CrossingsEditField_2.Editable = 'off';
            app.CrossingsEditField_2.Value = [];
            app.SquarecrossingsEditField_2Label = uilabel(app.AnimationresultsPanel);
            app.SquarecrossingsEditField_2Label.Text = 'Square crossings';
            app.SquarecrossingsEditField_2 = uieditfield(app.AnimationresultsPanel, 'numeric');
            app.SquarecrossingsEditField_2.ValueDisplayFormat = '%.0f';
            app.SquarecrossingsEditField_2.AllowEmpty = 'on';
            app.SquarecrossingsEditField_2.Editable = 'off';
            app.SquarecrossingsEditField_2.Value = [];
            app.PiestimateEditField_2Label = uilabel(app.AnimationresultsPanel);
            app.PiestimateEditField_2Label.Text = 'Pi estimate';
            app.PiestimateEditField_2 = uieditfield(app.AnimationresultsPanel, 'numeric');
            app.PiestimateEditField_2.ValueDisplayFormat = '%.5f';
            app.PiestimateEditField_2.AllowEmpty = 'on';
            app.PiestimateEditField_2.Editable = 'off';
            app.PiestimateEditField_2.Value = [];
            app.TotalneedlesEditField_2Label = uilabel(app.AnimationresultsPanel);
            app.TotalneedlesEditField_2Label.Text = 'Total needles';
            app.TotalneedlesEditField_2 = uieditfield(app.AnimationresultsPanel, 'numeric');
            app.TotalneedlesEditField_2.ValueDisplayFormat = '%.0f';
            app.TotalneedlesEditField_2.AllowEmpty = 'on';
            app.TotalneedlesEditField_2.Editable = 'off';
            app.TotalneedlesEditField_2.Value = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = buffonsNeedle

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end